classdef Analyze_smLD_Handy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        TrajectorymatrixEditFieldLabel  matlab.ui.control.Label
        TrajectorymatrixEditField       matlab.ui.control.EditField
        LoadTrajectoriesButton          matlab.ui.control.Button
        DiscardTrajectoryButton         matlab.ui.control.Button
        IdentifyChangePointsButton      matlab.ui.control.Button
        SaveandProceedButton            matlab.ui.control.Button
        LocationSliderLabel             matlab.ui.control.Label
        LocationSlider                  matlab.ui.control.Slider
        SelectedChangePointDropDownLabel  matlab.ui.control.Label
        SelectedChangePointDropDown     matlab.ui.control.DropDown
    end


    properties (Access = private)
        TrajectoryMatrix    % stores a matrix of intensity traces
        TrajectoryIndex     % identifies the current trajectory
        TrajectoryLength    % length of each trajectory, in frames
        Limit               % last trajectory
        ExposureTime        % exposure time of camera (s)
        ChangePoints        % frame index of change points for current trace
        % look I'd program this better but I'm not doing queues in MATLAB
    end

    methods (Access = private)
    
        function results = PlotCurrentTrace(app)
            l = app.TrajectoryLength;
            xs = linspace(1, l, l);
            ys = app.TrajectoryMatrix(app.TrajectoryIndex,:);
            plot(app.UIAxes, xs, ys);
           
        end
        
        function results = PlotChangePoints(app)
            idxs = linspace(1, length(app.ChangePoints), length(app.ChangePoints));
            %idxs_str = num2str(idxs);
            y=get(app.UIAxes,'ylim');
            hold(app.UIAxes, 'on');
            for i = 1:length(idxs)
                plot(app.UIAxes, [app.ChangePoints(i), app.ChangePoints(i)],y,'r');
            end
            hold(app.UIAxes, 'off');
        end
        
    end


    methods (Access = private)

        % Button pushed function: LoadTrajectoriesButton
        function LoadTrajectoriesButtonPushed(app, event)
            % load the matrix
            data = load(app.TrajectorymatrixEditField.Value);
            app.TrajectoryMatrix = data.mansel_int_array;
            app.ExposureTime = data.Exposuretime;
            app.TrajectoryIndex = 1;
            app.TrajectoryLength = length(data.mansel_int_array(1,:));
            app.Limit = length(data.mansel_int_array(:,1));
            
            % display the first trajectory
            PlotCurrentTrace(app);
            
        end

        % Value changed function: TrajectorymatrixEditField
        function TrajectorymatrixEditFieldValueChanged(app, event)
            value = app.TrajectorymatrixEditField.Value;
            
        end

        % Button pushed function: DiscardTrajectoryButton
        function DiscardTrajectoryButtonPushed(app, event)
            app.TrajectoryIndex = app.TrajectoryIndex + 1;
            if app.TrajectoryIndex > app.Limit
               delete(app); 
            else
                PlotCurrentTrace(app);
            end
        end

        % Button pushed function: IdentifyChangePointsButton
        function IdentifyChangePointsButtonPushed(app, event)
            change_points = findcp(app.TrajectoryMatrix(app.TrajectoryIndex,:));
            app.ChangePoints = change_points;
            
            % Display change points on the current plot
            PlotChangePoints(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Intensity Trace')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'Fluorescence Intensity (a.u.)')
            app.TrajectorymatrixEditFieldLabel = uilabel(app.UIFigure);
            app.TrajectorymatrixEditFieldLabel.Text = 'Trajectory matrix';
            app.TrajectorymatrixEditField = uieditfield(app.UIFigure, 'text');
            app.TrajectorymatrixEditField.ValueChangedFcn = createCallbackFcn(app, @TrajectorymatrixEditFieldValueChanged, true);
            app.LoadTrajectoriesButton = uibutton(app.UIFigure, 'push');
            app.LoadTrajectoriesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrajectoriesButtonPushed, true);
            app.LoadTrajectoriesButton.Text = 'Load Trajectories';
            app.DiscardTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.DiscardTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @DiscardTrajectoryButtonPushed, true);
            app.DiscardTrajectoryButton.Text = 'Discard Trajectory';
            app.IdentifyChangePointsButton = uibutton(app.UIFigure, 'push');
            app.IdentifyChangePointsButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyChangePointsButtonPushed, true);
            app.IdentifyChangePointsButton.Text = 'Identify Change Points';
            app.SaveandProceedButton = uibutton(app.UIFigure, 'push');
            app.SaveandProceedButton.Text = 'Save and Proceed';
            app.LocationSliderLabel = uilabel(app.UIFigure);
            app.LocationSliderLabel.Text = 'Location';
            app.LocationSlider = uislider(app.UIFigure);
            app.SelectedChangePointDropDownLabel = uilabel(app.UIFigure);
            app.SelectedChangePointDropDownLabel.Text = 'Selected Change Point';
            app.SelectedChangePointDropDown = uidropdown(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Analyze_smLD_Handy

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end